classdef RDHS_MainWnd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWnd       matlab.ui.Figure
        StartButton   matlab.ui.control.Button
        Label         matlab.ui.control.Label
        ModeSelector  matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        mode='ODHS';% Description
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            switch app.mode
                case 'ODHS'
                    
                    RDHS_ODHS_CodingWnd;
                    delete(app);
                case 'TDHS'
                    RDHS_TDHS_CodingWnd;
                    delete(app);
                case 'TDHSce'
                    RDHS_TDHSce_CodingWnd;
                     delete(app);
                    
                case 'TDHSpro'
                    %{
                    answer = questdlg('功能开发中确定进入？','测试版提醒','yes','no','yes');
                    switch answer
                        case 'yes'
                            RDHS_TDHSpro_CodingWnd;
                            delete(app);
                        case 'no'
                    end
                    %}
                    RDHS_TDHSpro_CodingWnd;
                    delete(app);
            end
        end

        % Value changed function: ModeSelector
        function ModeSelectorValueChanged(app, event)
            value = app.ModeSelector.Value;
            app.mode=value;
            
        end

        % Callback function
        function testButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWnd = uifigure;
            app.MainWnd.Name = 'RDHS';
            setAutoResize(app, app.MainWnd, true)
            app.StartButton = uibutton(app.MainWnd, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.MainWnd);
            app.Label.Text = '模式';
            app.ModeSelector = uilistbox(app.MainWnd);
            app.ModeSelector.Items = {'ODHS', 'TDHS', 'TDHSce', 'TDHSpro'};
            app.ModeSelector.ValueChangedFcn = createCallbackFcn(app, @ModeSelectorValueChanged, true);
            app.ModeSelector.Value = 'ODHS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RDHS_MainWnd()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWnd)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWnd)
        end
    end
end