classdef RDHS_ODHS_DecodingWnd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ODHS_Decoding                  matlab.ui.Figure
        mainwnd_btn                    matlab.ui.control.Button
        back_btn                       matlab.ui.control.Button
        title_label                    matlab.ui.control.Label
        run_btn                        matlab.ui.control.Button
        para_TabGroup                  matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Panel_5                        matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        Label_6                        matlab.ui.control.Label
        img_lamp                       matlab.ui.control.Lamp
        img_preview_panel              matlab.ui.container.Panel
        img_preview_UIAxes             matlab.ui.control.UIAxes
        get_img_btn                    matlab.ui.control.Button
        img_path_textarea              matlab.ui.control.TextArea
        Panel_2                        matlab.ui.container.Panel
        Label_20                       matlab.ui.control.Label
        msg_lamp                       matlab.ui.control.Lamp
        get_msg_btn                    matlab.ui.control.Button
        msg_preview_panel              matlab.ui.container.Panel
        Label_17                       matlab.ui.control.Label
        msg_preview_textarea           matlab.ui.control.TextArea
        Label_5                        matlab.ui.control.Label
        get_msg_type_dropdown          matlab.ui.control.DropDown
        DropDown_3Label                matlab.ui.control.Label
        overflow_type_dropdown         matlab.ui.control.DropDown
        msg_path_textarea              matlab.ui.control.TextArea
        Tab_2                          matlab.ui.container.Tab
        Panel_7                        matlab.ui.container.Panel
        character_support_switch       matlab.ui.control.Switch
        UnicodeLabel                   matlab.ui.control.Label
        encrypt_switch                 matlab.ui.control.Switch
        RC4Label                       matlab.ui.control.Label
        get_RC4_output_path_btn        matlab.ui.control.Button
        get_RC4_Key_btn                matlab.ui.control.Button
        Label_19                       matlab.ui.control.Label
        RC4_output_path_lamp           matlab.ui.control.Lamp
        RC4_key_EditField              matlab.ui.control.EditField
        RC4LampLabel                   matlab.ui.control.Label
        RC4_key_lamp                   matlab.ui.control.Lamp
        RC4_path_textarea              matlab.ui.control.TextArea
        Tab_3                          matlab.ui.container.Tab
        Panel_6                        matlab.ui.container.Panel
        analyse_img_checkbox           matlab.ui.control.CheckBox
        analyse_histogram_checkbox     matlab.ui.control.CheckBox
        analyse_para_checkbox          matlab.ui.control.CheckBox
        get_output_dir_path_btn        matlab.ui.control.Button
        output_file_path_textarea      matlab.ui.control.TextArea
        output_msg_type_dropdownLabel  matlab.ui.control.Label
        output_msg_type_dropdown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        FileIO  % Description% Description
        RC4 % Description
        ODHS % Description
        DC % Description
        DP % Description
        
        %switch_flag
        input_msg_mod
        output_msg_mod
        
        img_ready_flag=false
        msg_ready_flag=false
        analyse_img_flag=false
        analyse_histogram_flag=false
        analyse_para_flag=false
        RC4_ready_flag=false
        RC4_output_path_flag=false
        
        RC4_flag=false
        ext_flag=false
        overload_solution
        data_type
        %pararmeter


        ori_msg % Description
        final_img
        img_H
        img_V
        output_dir_path
        
        
        gray_minId256
        gray_maxId256
        overhead_info_len
        
        RC4_key
        output_RC4dir_path
        
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = public)


    end
    
    methods (Access = private)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath('bin');addpath('lib');addpath('out');addpath('res');
            
            app.FileIO=File_IO;
            app.RC4=Rivest_Cipher_4;
            app.ODHS=One_Dimensional_Histogram_Shift_Coding;
            app.DC=Data_Convert;
            app.DP=Data_Process;


            app.img_ready_flag=false;
            app.msg_ready_flag=false;
            app.analyse_img_flag=false;
            app.analyse_histogram_flag=0;
            app.analyse_para_flag=0;
            app.RC4_ready_flag=false;
            app.RC4_output_path_flag=false;


            app.gray_minId256=1;
            app.gray_maxId256=256;
            app.overhead_info_len=0;
            
            
            app.input_msg_mod=get(app.get_msg_type_dropdown,'Value');
            app.output_msg_mod=get(app.output_msg_type_dropdown,'Value');
            app.overload_solution=get(app.overflow_type_dropdown,'Value');
            [~,~,~,~,app.output_dir_path] =app.FileIO.getCurPath();
            set(app.output_file_path_textarea,'Value',app.output_dir_path);
            app.data_type='A';
            app.RC4_flag=false;
            app.ext_flag=false;
        end

        % Callback function
        function StartButtonPushed(app, event)
            
        end

        % Button pushed function: get_img_btn
        function get_img_btnPushed(app, event)
            [~,final_img_filepath]=app.FileIO.openOutFileDialog('bmp','MarkedImg_ODHS.bmp');
            app.final_img=app.FileIO.readImgFile(final_img_filepath);
            ax=app.img_preview_UIAxes;
            imshow(app.final_img,'parent',ax);
            set(ax,'visible','off');
            set(app.img_path_textarea,'Value',final_img_filepath);
            [app.img_H,app.img_V]=size(app.final_img);
            set(app.img_lamp,'Color',[0,1,0]);
            
            app.img_ready_flag=true;
        end

        % Button pushed function: run_btn
        function RunButtonPushed(app, event)
            if(app.img_ready_flag&&app.msg_ready_flag)
                final_gray_cnts=Init_Gray_Cnts(app.final_img);%记录灰度
                msg_bits_flag=1;
                odhs_img=app.final_img;
                [~,msg_bits_max_len] = app.ODHS.getMaxEmbeddedCapacity( odhs_img,app.data_type );
                [msg_bits,odhs_img] = app.ODHS.decodingImgToMsg( app.gray_minId256,app.gray_maxId256,msg_bits_max_len,odhs_img);
                
                secret_msg=app.DC.bi2StrUni(msg_bits);%转码
                odhs_gray_cnts=Init_Gray_Cnts(odhs_img);%记录灰度
                
                msg_pak=secret_msg;
                
                if((app.RC4_flag)&&(app.ext_flag))
                    %RC4解密
                    [ msg_pak,data_header ] = app.RC4.decodingByFile(secret_msg,app.RC4_flag,app.ext_flag);
                    app.FileIO.generateStrFile([app.output_RC4dir_path,'\RC4_decoding.txt'],msg_pak);
                    
                end


                %平移直方图
                ori_img=odhs_img;
                ori_img=app.ODHS.translationHistogram( app.gray_minId256,app.gray_maxId256,ori_img,odhs_img,'Decoding' );
                [ori_msg,Locatemap]=app.ODHS.getLocatemap(msg_pak,app.overhead_info_len);
                %非零零值点还原
                odhs_img=app.ODHS.zeroHistogramDecoding(app.gray_minId256,ori_img,Locatemap);
                ori_gray_cnts=Init_Gray_Cnts(odhs_img);%记录灰度
                
                recoverd_msg=ori_msg;
                
                
                %分析
                analyse_flag=app.analyse_img_flag+app.analyse_histogram_flag;
                switch analyse_flag
                    case 2
                        figure;
                        
                        subplot(2,3,1);imshow(app.final_img);title('秘密lena');
                        subplot(2,3,2);imshow(odhs_img);title('平移lena');
                        subplot(2,3,3);imshow(ori_img);title('原始lena');
                        subplot(2,3,4);bar(0:255,final_gray_cnts,'grouped');title('秘密直方图');
                        subplot(2,3,5);bar(0:255,odhs_gray_cnts,'grouped');title('平移直方图');
                        subplot(2,3,6);bar(0:255,ori_gray_cnts,'grouped');title('原始直方图');
                        
                        
                    case 1
                        if(app.analyse_img_flag)
                            
                            figure;
                            subplot(1,3,1);imshow(app.final_img);title('秘密lena');
                            subplot(1,3,2);imshow(odhs_img);title('平移lena');
                            subplot(1,3,3);imshow(ori_img);title('原始lena');
                        end
                        if(app.analyse_histogram_flag)
                            figure();
                            subplot(1,3,1);bar(0:255,final_gray_cnts,'grouped');title('秘密直方图');
                            subplot(1,3,2);bar(0:255,odhs_gray_cnts,'grouped');title('平移直方图');
                            subplot(1,3,3);bar(0:255,ori_gray_cnts,'grouped');title('原始直方图');
                            
                        end
                    case 0
                end
                if(app.analyse_para_flag)
                    psnr=app.DP.psnr(app.final_img,ori_img,8);
                    msgbox(['PSNR：',num2str(psnr)],'Data');
                end
                if ~exist([app.output_dir_path,'\Extract_ODHS'])
                    mkdir([app.output_dir_path,'\Extract_ODHS'])         % 若不存在，在当前目录中产生一个子目录‘Figure’
                end
                output_path=[app.output_dir_path,'\Extract_ODHS'];
                app.FileIO.generateImgFile([output_path,'\RecoveredImg.bmp'],ori_img);


                 switch app.output_msg_mod
                    case 'file_str'
                        %[app.ori_msg]=app.DC.bi2StrUni(recoverd_msg);
                        [app.ori_msg]=recoverd_msg;
                        app.FileIO.generateStrFile([output_path,'\RecoverdMsg_str_TDHS.txt'],app.ori_msg);
                    case 'file_dec'
                        [app.ori_msg]=recoverd_msg;
                        app.FileIO.generateDecFile( [output_path,'\RecoverdMsg_TDHS.txt'],app.ori_msg)
                    case 'file_img'
                        if(mod(length(recoverd_msg)-28,16)==0)
                           % [app.ori_msg]=app.DC.bi2img(recoverd_msg);
                           [app.ori_msg]=recoverd_msg;
                            app.FileIO.generateImgFile([output_path,'\RecoverdMsgImg_TDHS.bmp'],app.ori_msg);
                        else
                            [app.ori_msg]=recoverd_msg;
                            app.FileIO.generateDecFile( [output_path,'\RecoverdMsg_TDHS.txt'],app.ori_msg)
                            msgbox('illegal img ;forced ouput file');
                        end
                    case 'msgbox'
                        [app.ori_msg]=num2str(recoverd_msg);
                        msgbox(app.ori_msg);
                    otherwise
                        error('illegal parameter');
                end


            else
                msgbox('not ready','warning');
            end


            msgbox('Sucess!');
            
        end

        % Value changed function: get_msg_type_dropdown
        function get_msg_type_dropdownValueChanged(app, event)
            value = app.get_msg_type_dropdown.Value;
            set(app.msg_lamp,'Color',[1,0,0]);
            if(strcmp(value,'input'))
                set(app.msg_preview_textarea,'Editable','on');
                set(app.msg_preview_textarea,'Value','use comma');
                
            else
                set(app.msg_preview_textarea,'Editable','off');
                
            end
            app.msg_ready_flag=false;
            app.input_msg_mod=value;
        end

        % Button pushed function: get_msg_btn
        function get_msg_btnButtonPushed(app, event)
            
            switch app.input_msg_mod
                case 'file'
                    %取信息
                    [~,para_msg_filepath]=app.FileIO.openOutFileDialog('txt','para_ODHS_coding.txt');
                    [app.gray_minId256,app.gray_maxId256,app.overhead_info_len]=app.ODHS.getParaByFile(para_msg_filepath);
                    
                    set(app.msg_path_textarea,'Value',para_msg_filepath);
                    set(app.msg_preview_textarea,'Editable','off');
                    
                case 'input'
                    %ans= inputdlg('Embedded message','please input message',1);
                    %app.ori_msg =ans{1};
                    msgs=get(app.msg_preview_textarea,'Value');
                    read_data=textscan(char(msgs),'%d,%d,%d');
                    app.gray_minId256=read_data{1};
                    app.gray_maxId256=read_data{2};
                    app.overhead_info_len=read_data{3};
                    if(1<=app.gray_minId256&&255>=app.gray_minId256&&1<=app.gray_maxId256&&255>=app.gray_maxId256&&app.overhead_info_len>0)
                        
                    else
                        msgbox('illegal parameter!','warning')
                        error('illegal parameter');
                    end
                otherwise
                    error('get msg mode error');
            end
            set(app.msg_preview_textarea,'Value',[num2str(app.gray_minId256),',',num2str(app.gray_maxId256),',',num2str(app.overhead_info_len)]);
            set(app.msg_lamp,'Color',[0,1,0]);
            
            app.msg_ready_flag=true;
        end

        % Value changed function: msg_preview_textarea
        function msg_preview_textareaValueChanged(app, event)
            value = app.msg_preview_textarea.Value;
            app.ori_msg=value;
        end

        % Callback function
        function encrypt_switchValueChanged(app, event)
            value = app.encrypt_switch.Value;
            if(app.RC4_ready_flag&&app.RC4_output_path_flag)
                
                switch value
                    case 'On'
                        app.RC4_flag=true;
                    case 'Off'
                        
                        app.RC4_flag=false;
                        set(app.RC4_key_lamp,'Color',[1,0,0]);
                        set(app.RC4_output_path_lamp,'Color',[1,0,0]);
                        
                        set(app.RC4_key_EditField,'Value','key');
                        set(app.RC4_path_textarea,'Value','path');
                        
                end
                if(app.RC4_flag)%RC4 加密开启后强制数据类型位‘U’
                    app.data_type='U';
                    set(app.character_support_switch,'Value','On');
                end
            else
                switch value
                    case 'On'
                        msgbox('not ready','warning');
                        set(app.encrypt_switch,'Value','Off');
                    case 'Off'
                end
                
            end
        end

        % Value changed function: character_support_switch
        function character_support_switchValueChanged(app, event)
            value = app.character_support_switch.Value;
            switch value
                case 'On'
                    app.data_type='U';
                    
                case 'Off'
                    app.data_type='A';
            end
        end

        % Button pushed function: get_RC4_Key_btn
        function get_RC4_Key_btnButtonPushed(app, event)
            
            [~,RC4_key_filepath]=app.FileIO.openResFileDialog('txt','RC4_key.txt');
            app.RC4_key=app.FileIO.readStrFile(RC4_key_filepath);
            app.RC4_ready_flag=true;
            set(app.RC4_key_lamp,'Color',[0,1,0]);
            set(app.RC4_key_EditField,'Value',char(app.RC4_key));
            
            
        end

        % Button pushed function: get_RC4_output_path_btn
        function get_RC4_output_path_btnButtonPushed(app, event)
            app.output_RC4dir_path=app.FileIO.openDirDialog('out','输出文件路径');
            app.RC4_output_path_flag=true;
            set(app.RC4_output_path_lamp,'Color',[0,1,0]);
            
            set(app.RC4_path_textarea,'Value',char(app.output_RC4dir_path));
            
            
        end

        % Button pushed function: get_output_dir_path_btn
        function get_output_dir_path_btnButtonPushed(app, event)
            app.output_dir_path=app.FileIO.openDirDialog('out','输出文件路径');
            set(app.output_file_path_textarea,'Value',app.output_dir_path);
        end

        % Value changed function: analyse_img_checkbox
        function analyse_img_checkboxValueChanged(app, event)
            value = app.analyse_img_checkbox.Value;
            switch value
                case 1
                    app.analyse_img_flag=true;
                case 0
                    app.analyse_img_flag=false;
            end
            
        end

        % Value changed function: analyse_histogram_checkbox
        function analyse_histogram_checkboxValueChanged(app, event)
            value = app.analyse_histogram_checkbox.Value;
            switch value
                case 1
                    app.analyse_histogram_flag=true;
                case 0
                    app.analyse_histogram_flag=false;
            end
        end

        % Value changed function: analyse_para_checkbox
        function analyse_para_checkboxValueChanged(app, event)
            value = app.analyse_para_checkbox.Value;
            switch value
                case 1
                    app.analyse_para_flag=true;
                case 0
                    app.analyse_para_flag=false;
            end
        end

        % Button pushed function: back_btn
        function back_btnButtonPushed(app, event)
            RDHS_ODHS_CodingWnd;
            delete(app);
        end

        % Button pushed function: mainwnd_btn
        function mainwnd_btnButtonPushed(app, event)
            RDHS_MainWnd;
            delete(app);
        end

        % Value changed function: output_msg_type_dropdown
        function output_msg_type_dropdownValueChanged(app, event)
            value = app.output_msg_type_dropdown.Value;
            app.output_msg_mod=value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ODHS_Decoding = uifigure;
            app.ODHS_Decoding.Name = 'ODHS_Decoding';
            setAutoResize(app, app.ODHS_Decoding, true)
            app.mainwnd_btn = uibutton(app.ODHS_Decoding, 'push');
            app.mainwnd_btn.ButtonPushedFcn = createCallbackFcn(app, @mainwnd_btnButtonPushed, true);
            app.mainwnd_btn.Text = '首页';
            app.back_btn = uibutton(app.ODHS_Decoding, 'push');
            app.back_btn.ButtonPushedFcn = createCallbackFcn(app, @back_btnButtonPushed, true);
            app.back_btn.Text = 'Coding→';
            app.title_label = uilabel(app.ODHS_Decoding);
            app.title_label.Text = 'One_Dimensional_Histogram_Shift_Decoding RDHS';
            app.run_btn = uibutton(app.ODHS_Decoding, 'push');
            app.run_btn.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.run_btn.Text = '运行';
            app.para_TabGroup = uitabgroup(app.ODHS_Decoding);
            app.Tab = uitab(app.para_TabGroup);
            app.Tab.Title = '必选参数';
            app.Tab.Units = 'pixels';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '必选';
            app.Panel = uipanel(app.Panel_5);
            app.Panel.Title = '图像';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '嵌入图像';
            app.img_lamp = uilamp(app.Panel);
            app.img_preview_panel = uipanel(app.Panel);
            app.img_preview_panel.Title = '预览图像';
            app.img_preview_UIAxes = uiaxes(app.img_preview_panel);
            app.get_img_btn = uibutton(app.Panel, 'push');
            app.get_img_btn.ButtonPushedFcn = createCallbackFcn(app, @get_img_btnPushed, true);
            app.get_img_btn.Text = '获取';
            app.img_path_textarea = uitextarea(app.Panel);
            app.img_path_textarea.Editable = 'off';
            app.img_path_textarea.Value = {'路径'};
            app.Panel_2 = uipanel(app.Panel_5);
            app.Panel_2.Title = '信息';
            app.Label_20 = uilabel(app.Panel_2);
            app.Label_20.Text = '解码参数';
            app.msg_lamp = uilamp(app.Panel_2);
            app.get_msg_btn = uibutton(app.Panel_2, 'push');
            app.get_msg_btn.ButtonPushedFcn = createCallbackFcn(app, @get_msg_btnButtonPushed, true);
            app.get_msg_btn.Text = '获取';
            app.msg_preview_panel = uipanel(app.Panel_2);
            app.msg_preview_panel.Title = '预览信息';
            app.Label_17 = uilabel(app.msg_preview_panel);
            app.Label_17.Text = '信息预览';
            app.msg_preview_textarea = uitextarea(app.msg_preview_panel);
            app.msg_preview_textarea.ValueChangedFcn = createCallbackFcn(app, @msg_preview_textareaValueChanged, true);
            app.msg_preview_textarea.Editable = 'off';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '获取方式';
            app.get_msg_type_dropdown = uidropdown(app.Panel_2);
            app.get_msg_type_dropdown.Items = {'file', 'input'};
            app.get_msg_type_dropdown.ValueChangedFcn = createCallbackFcn(app, @get_msg_type_dropdownValueChanged, true);
            app.get_msg_type_dropdown.Value = 'file';
            app.DropDown_3Label = uilabel(app.Panel_2);
            app.DropDown_3Label.Text = '溢出处理';
            app.overflow_type_dropdown = uidropdown(app.Panel_2);
            app.overflow_type_dropdown.Items = {'clip', 'group'};
            app.overflow_type_dropdown.Value = 'clip';
            app.msg_path_textarea = uitextarea(app.Panel_2);
            app.msg_path_textarea.Editable = 'off';
            app.msg_path_textarea.Value = {'路径'; ''};
            app.Tab_2 = uitab(app.para_TabGroup);
            app.Tab_2.Title = '额外参数';
            app.Tab_2.Units = 'pixels';
            app.Panel_7 = uipanel(app.Tab_2);
            app.Panel_7.Title = '额外';
            app.character_support_switch = uiswitch(app.Panel_7, 'slider');
            app.character_support_switch.ValueChangedFcn = createCallbackFcn(app, @character_support_switchValueChanged, true);
            app.UnicodeLabel = uilabel(app.Panel_7);
            app.UnicodeLabel.Text = 'Unicode支持';
            app.encrypt_switch = uiswitch(app.Panel_7, 'slider');
            app.encrypt_switch.ValueChangedFcn = createCallbackFcn(app, @encrypt_switchValueChanged, true);
            app.RC4Label = uilabel(app.Panel_7);
            app.RC4Label.Text = 'RC4解密';
            app.get_RC4_output_path_btn = uibutton(app.Panel_7, 'push');
            app.get_RC4_output_path_btn.ButtonPushedFcn = createCallbackFcn(app, @get_RC4_output_path_btnButtonPushed, true);
            app.get_RC4_output_path_btn.Text = '获取';
            app.get_RC4_Key_btn = uibutton(app.Panel_7, 'push');
            app.get_RC4_Key_btn.ButtonPushedFcn = createCallbackFcn(app, @get_RC4_Key_btnButtonPushed, true);
            app.get_RC4_Key_btn.Text = '获取';
            app.Label_19 = uilabel(app.Panel_7);
            app.Label_19.Text = '输出路径';
            app.RC4_output_path_lamp = uilamp(app.Panel_7);
            app.RC4_key_EditField = uieditfield(app.Panel_7, 'text');
            app.RC4_key_EditField.Editable = 'off';
            app.RC4LampLabel = uilabel(app.Panel_7);
            app.RC4LampLabel.Text = 'RC4密钥';
            app.RC4_key_lamp = uilamp(app.Panel_7);
            app.RC4_path_textarea = uitextarea(app.Panel_7);
            app.RC4_path_textarea.Editable = 'off';
            app.Tab_3 = uitab(app.para_TabGroup);
            app.Tab_3.Title = '输出参数';
            app.Tab_3.Units = 'pixels';
            app.Panel_6 = uipanel(app.Tab_3);
            app.Panel_6.Title = '输出';
            app.analyse_img_checkbox = uicheckbox(app.Panel_6);
            app.analyse_img_checkbox.ValueChangedFcn = createCallbackFcn(app, @analyse_img_checkboxValueChanged, true);
            app.analyse_img_checkbox.Text = '图像对比';
            app.analyse_histogram_checkbox = uicheckbox(app.Panel_6);
            app.analyse_histogram_checkbox.ValueChangedFcn = createCallbackFcn(app, @analyse_histogram_checkboxValueChanged, true);
            app.analyse_histogram_checkbox.Text = '直方图';
            app.analyse_para_checkbox = uicheckbox(app.Panel_6);
            app.analyse_para_checkbox.ValueChangedFcn = createCallbackFcn(app, @analyse_para_checkboxValueChanged, true);
            app.analyse_para_checkbox.Text = '数据';
            app.get_output_dir_path_btn = uibutton(app.Panel_6, 'push');
            app.get_output_dir_path_btn.ButtonPushedFcn = createCallbackFcn(app, @get_output_dir_path_btnButtonPushed, true);
            app.get_output_dir_path_btn.Text = '修改';
            app.output_file_path_textarea = uitextarea(app.Panel_6);
            app.output_file_path_textarea.Editable = 'off';
            app.output_msg_type_dropdownLabel = uilabel(app.Panel_6);
            app.output_msg_type_dropdownLabel.Text = '输出方式';
            app.output_msg_type_dropdown = uidropdown(app.Panel_6);
            app.output_msg_type_dropdown.Items = {'file_str', 'file_dec', 'file_img', 'msgbox'};
            app.output_msg_type_dropdown.ValueChangedFcn = createCallbackFcn(app, @output_msg_type_dropdownValueChanged, true);
            app.output_msg_type_dropdown.Value = 'file_str';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RDHS_ODHS_DecodingWnd()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ODHS_Decoding)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ODHS_Decoding)
        end
    end
end