classdef RDHS_TDHSce_CodingWnd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TDHSce_Coding              matlab.ui.Figure
        mainwnd_btn                matlab.ui.control.Button
        back_btn                   matlab.ui.control.Button
        title_label                matlab.ui.control.Label
        run_btn                    matlab.ui.control.Button
        para_TabGroup              matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        Panel_5                    matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        Label_6                    matlab.ui.control.Label
        img_lamp                   matlab.ui.control.Lamp
        img_preview_panel          matlab.ui.container.Panel
        img_preview_UIAxes         matlab.ui.control.UIAxes
        get_img_btn                matlab.ui.control.Button
        img_path_textarea          matlab.ui.control.TextArea
        Panel_2                    matlab.ui.container.Panel
        Lamp_2Label                matlab.ui.control.Label
        msg_lamp                   matlab.ui.control.Lamp
        get_msg_btn                matlab.ui.control.Button
        msg_preview_panel          matlab.ui.container.Panel
        Label_17                   matlab.ui.control.Label
        msg_preview_textarea       matlab.ui.control.TextArea
        Label_5                    matlab.ui.control.Label
        get_msg_type_dropdown      matlab.ui.control.DropDown
        msg_path_textarea          matlab.ui.control.TextArea
        Label_18                   matlab.ui.control.Label
        embed_splite_editfield     matlab.ui.control.NumericEditField
        Tab_3                      matlab.ui.container.Tab
        Panel_6                    matlab.ui.container.Panel
        analyse_para_checkbox      matlab.ui.control.CheckBox
        get_output_dir_path_btn    matlab.ui.control.Button
        output_file_path_textarea  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        FileIO  % Description% Description
        TDHSce % Description
        DC % Description
        DP % Description
        
        %switch_flag
        input_msg_mod
        
        img_ready_flag=false
        msg_ready_flag=false
        analyse_histogram_flag=false
        analyse_para_flag=false
        
        %pararmeter


        ori_bin % Description
        ori_img
        img_H
        img_V
        output_dir_path
        
        bit_len
        L
        embed_bin_len
        capacity
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = public)


    end
    
    methods (Access = private)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath('bin');addpath('lib');addpath('out');addpath('res');
            
            app.FileIO=File_IO;
            app.TDHSce=Two_Dimensional_Histogram_Shift_Contrast_Enhancement;
            app.DC=Data_Convert;
            app.DP=Data_Process;


            app.img_ready_flag=false;
            app.msg_ready_flag=false;
            app.analyse_histogram_flag=0;
            app.analyse_para_flag=0;
            
            app.bit_len=16;
            app.L=1;%分裂次数
            
            app.embed_bin_len=intmax;
            app.capacity=0;
            
            app.input_msg_mod=get(app.get_msg_type_dropdown,'Value');
            [~,~,~,~,app.output_dir_path] =app.FileIO.getCurPath();
            set(app.output_file_path_textarea,'Value',app.output_dir_path);
            
            
        end

        % Callback function
        function StartButtonPushed(app, event)
            
        end

        % Button pushed function: get_img_btn
        function get_img_btnPushed(app, event)
            [~,ori_img_filepath]=app.FileIO.openResFileDialog('bmp','lena512.bmp');
            app.ori_img=app.FileIO.readImgFile(ori_img_filepath);
            ax=app.img_preview_UIAxes;
            imshow(app.ori_img,'parent',ax);
            set(ax,'visible','off');
            set(app.img_path_textarea,'Value',ori_img_filepath);
            [app.img_H,app.img_V]=size(app.ori_img);
            set(app.img_lamp,'Color',[0,1,0]);
            app.img_ready_flag=true;
        end

        % Button pushed function: run_btn
        function RunButtonPushed(app, event)
            if(app.img_ready_flag&&app.msg_ready_flag)


                embed_L=0;%从0开始嵌入
                input_img=app.ori_img;
                input_bin=app.ori_bin;
                bar = waitbar(0,'预处理中...');    % 显示进度条
                while embed_L~=app.L
                    [output_img]=app.TDHSce.mainCoding(input_img,input_bin,embed_L);
                    input_img=output_img;
                    input_bin=[];%暂未用信息分段所以下一次分段嵌入直接设空
                    
                    
                    bar_len = app.L;
                    i=embed_L;
                    str=['计算中...',num2str(100*i/bar_len),'%'];    % 百分比形式显示处理进程
                    waitbar(i/bar_len,bar,str)                       % 更新进度条bar
                    
                    embed_L=embed_L+1;
                end
                close(bar);
                marked_img=output_img;
                
                
                if ~exist([app.output_dir_path,'\Extract_TDHSce'])
                    mkdir([app.output_dir_path,'\Extract_TDHSce'])         % 若不存在，在当前目录中产生一个子目录‘Figure’
                end
                output_path=[app.output_dir_path,'\Extract_TDHSce'];
                
                app.FileIO.generateImgFile([output_path,'\MarkedImg_TDHSce.bmp'],uint8(marked_img));
                
                if(app.analyse_para_flag)
                    psnr=app.DP.psnr(app.ori_img,marked_img,8);
                    mse=app.DP.mse(app.ori_img,marked_img);
                    msgbox(['PSNR：',num2str(psnr),'MSE:',num2str(mse)],'Data');
                end
                
                msgbox('Sucess!');
            else
                
                msgbox('not ready','warning');
            end
            
            
        end

        % Value changed function: get_msg_type_dropdown
        function get_msg_type_dropdownValueChanged(app, event)
            value = app.get_msg_type_dropdown.Value;
            set(app.msg_lamp,'Color',[1,0,0])
            if(strcmp(value,'input'))
                set(app.msg_preview_textarea,'Editable','on');
            end
            app.msg_ready_flag=false;
            app.input_msg_mod=value;
        end

        % Button pushed function: get_msg_btn
        function get_msg_btnButtonPushed(app, event)


            switch app.input_msg_mod
                case 'file_str'
                    [~,ori_msg_filepath]=app.FileIO.openResFileDialog('txt','msg.txt');
                    [ori_msg_str]=app.FileIO.readStrFile(ori_msg_filepath);
                    [app.ori_bin]=app.DC.str2BiUni(ori_msg_str);
                    set(app.msg_path_textarea,'Value',ori_msg_filepath);
                    set(app.msg_preview_textarea,'Editable','off');
                case'file_img'
                    
                    [~,ori_msg_filepath]=app.FileIO.openResFileDialog('bmp','lena16.bmp');
                    ori_img_file=app.FileIO.readImgFile(ori_msg_filepath);
                    [app.ori_bin]=app.DC.img2bi(ori_img_file);
                    set(app.msg_path_textarea,'Value',ori_msg_filepath);
                    set(app.msg_preview_textarea,'Editable','off');
                case 'input'
                    set(app.msg_preview_textarea,'Editable','off');
                    app.ori_msg=get(app.msg_preview_textarea,'Value');
                case 'randkey'
                    randkey_len_str = inputdlg('请输入随机数长度','Input_msg_rand_len',1);
                    randkey_len=str2double(randkey_len_str{1});
                    randkey_seed_str = inputdlg('请输入随机种子','Input_msg_randkey',1);
                    randkey_seed=str2double(randkey_seed_str{1});
                    [app.ori_bin]=app.DP.randBin(randkey_seed,randkey_len);
                    set(app.msg_preview_textarea,'Editable','off');
                otherwise
                    error('get msg mode error');
            end
            set(app.msg_preview_textarea,'Value',num2str(app.ori_bin));
            set(app.msg_lamp,'Color',[0,1,0]);
            app.msg_ready_flag=true;
        end

        % Value changed function: msg_preview_textarea
        function msg_preview_textareaValueChanged(app, event)
            value = app.msg_preview_textarea.Value;
            app.ori_bin=value;
        end

        % Callback function
        function encrypt_switchValueChanged(app, event)
            value = app.encrypt_switch.Value;
            if(app.RC4_ready_flag&&app.RC4_output_path_flag)
                
                switch value
                    case 'On'
                        app.RC4_flag=true;
                    case 'Off'
                        
                        app.RC4_flag=false;
                        set(app.RC4_key_lamp,'Color',[1,0,0]);
                        set(app.RC4_output_path_lamp,'Color',[1,0,0]);
                        
                        set(app.RC4_key_EditField,'Value','key');
                        set(app.RC4_path_textarea,'Value','path');
                        
                end
                if(app.RC4_flag)%RC4 加密开启后强制数据类型位‘U’
                    app.data_type='U';
                    set(app.character_support_switch,'Value','On');
                end
            else
                switch value
                    case 'On'
                        msgbox('not ready','warning');
                        set(app.encrypt_switch,'Value','Off');
                    case 'Off'
                end
                
            end
        end

        % Callback function
        function character_support_switchValueChanged(app, event)
            value = app.character_support_switch.Value;
            switch value
                case 'On'
                    app.data_type='U';
                    
                case 'Off'
                    app.data_type='A';
            end
        end

        % Callback function
        function get_RC4_Key_btnButtonPushed(app, event)
            
            [~,RC4_key_filepath]=app.FileIO.openResFileDialog('txt','RC4_key.txt');
            app.RC4_key=app.FileIO.readStrFile(RC4_key_filepath);
            app.RC4_ready_flag=true;
            set(app.RC4_key_lamp,'Color',[0,1,0]);
            set(app.RC4_key_EditField,'Value',char(app.RC4_key));
            
            
        end

        % Callback function
        function get_RC4_output_path_btnButtonPushed(app, event)
            app.output_RC4dir_path=app.FileIO.openDirDialog('out','输出文件路径');
            app.RC4_output_path_flag=true;
            set(app.RC4_output_path_lamp,'Color',[0,1,0]);
            
            set(app.RC4_path_textarea,'Value',char(app.output_RC4dir_path));
            
            
        end

        % Button pushed function: get_output_dir_path_btn
        function get_output_dir_path_btnButtonPushed(app, event)
            app.output_dir_path=app.FileIO.openDirDialog('out','输出文件路径');
            set(app.output_file_path_textarea,'Value',app.output_dir_path);
        end

        % Callback function
        function analyse_img_checkboxValueChanged(app, event)
            value = app.analyse_img_checkbox.Value;
            switch value
                case 1
                    app.analyse_img_flag=true;
                case 0
                    app.analyse_img_flag=false;
            end
            
        end

        % Callback function
        function analyse_histogram_checkboxValueChanged(app, event)
            value = app.analyse_histogram_checkbox.Value;
            switch value
                case 1
                    app.analyse_histogram_flag=true;
                case 0
                    app.analyse_histogram_flag=false;
            end
        end

        % Value changed function: analyse_para_checkbox
        function analyse_para_checkboxValueChanged(app, event)
            value = app.analyse_para_checkbox.Value;
            switch value
                case 1
                    app.analyse_para_flag=true;
                case 0
                    app.analyse_para_flag=false;
            end
        end

        % Button pushed function: back_btn
        function back_btnButtonPushed(app, event)
            RDHS_TDHSce_DecodingWnd;
            delete(app);
        end

        % Button pushed function: mainwnd_btn
        function mainwnd_btnButtonPushed(app, event)
            RDHS_MainWnd;
            delete(app);
        end

        % Callback function
        function embed_chanel_btnButtonPushed(app, event)
            value=get(app.embed_splite_editfield,'Value');
            flag=1;
            for(i=1:length(app.L))
                if(app.L(i)==value)
                    flag=0;
                end
            end
            if(flag)
                temp=app.L;
                temp(end+1)=value;
                app.L=[];
                for i=1:length(temp)
                    [val,id]=min(temp);
                    temp(id)=intmax;
                    app.L(end+1)=val;
                end
                
                str='';
                for(i=1:length(app.L))
                    if(isempty(str))
                        str=num2str(app.L(i));
                    else
                        str=[str,',',num2str(app.L(i))];
                    end
                end
                disp(app.L);
                
                set(app.embed_chanel_preview_textarea,'Value',str);
            end
            
        end

        % Value changed function: embed_splite_editfield
        function embed_splite_editfieldValueChanged(app, event)
            value = app.embed_splite_editfield.Value;
            app.L=value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TDHSce_Coding = uifigure;
            app.TDHSce_Coding.Name = 'TDHSce_Coding';
            setAutoResize(app, app.TDHSce_Coding, true)
            app.mainwnd_btn = uibutton(app.TDHSce_Coding, 'push');
            app.mainwnd_btn.ButtonPushedFcn = createCallbackFcn(app, @mainwnd_btnButtonPushed, true);
            app.mainwnd_btn.Text = '首页';
            app.back_btn = uibutton(app.TDHSce_Coding, 'push');
            app.back_btn.ButtonPushedFcn = createCallbackFcn(app, @back_btnButtonPushed, true);
            app.back_btn.Text = 'Decoding→';
            app.title_label = uilabel(app.TDHSce_Coding);
            app.title_label.Text = 'Two_Dimensional_Histogram_Shift_Coding_with_CE RDHS';
            app.run_btn = uibutton(app.TDHSce_Coding, 'push');
            app.run_btn.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.run_btn.Text = '运行';
            app.para_TabGroup = uitabgroup(app.TDHSce_Coding);
            app.Tab = uitab(app.para_TabGroup);
            app.Tab.Title = '必选参数';
            app.Tab.Units = 'pixels';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '必选';
            app.Panel = uipanel(app.Panel_5);
            app.Panel.Title = '图像';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '载体图像';
            app.img_lamp = uilamp(app.Panel);
            app.img_preview_panel = uipanel(app.Panel);
            app.img_preview_panel.Title = '预览图像';
            app.img_preview_UIAxes = uiaxes(app.img_preview_panel);
            app.get_img_btn = uibutton(app.Panel, 'push');
            app.get_img_btn.ButtonPushedFcn = createCallbackFcn(app, @get_img_btnPushed, true);
            app.get_img_btn.Text = '获取';
            app.img_path_textarea = uitextarea(app.Panel);
            app.img_path_textarea.Editable = 'off';
            app.img_path_textarea.Value = {'路径'};
            app.Panel_2 = uipanel(app.Panel_5);
            app.Panel_2.Title = '信息';
            app.Lamp_2Label = uilabel(app.Panel_2);
            app.Lamp_2Label.Text = '嵌入信息';
            app.msg_lamp = uilamp(app.Panel_2);
            app.get_msg_btn = uibutton(app.Panel_2, 'push');
            app.get_msg_btn.ButtonPushedFcn = createCallbackFcn(app, @get_msg_btnButtonPushed, true);
            app.get_msg_btn.Text = '获取';
            app.msg_preview_panel = uipanel(app.Panel_2);
            app.msg_preview_panel.Title = '预览信息';
            app.Label_17 = uilabel(app.msg_preview_panel);
            app.Label_17.Text = '信息预览';
            app.msg_preview_textarea = uitextarea(app.msg_preview_panel);
            app.msg_preview_textarea.ValueChangedFcn = createCallbackFcn(app, @msg_preview_textareaValueChanged, true);
            app.msg_preview_textarea.Editable = 'off';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '获取方式';
            app.get_msg_type_dropdown = uidropdown(app.Panel_2);
            app.get_msg_type_dropdown.Items = {'file_str', 'file_img', 'input', 'randkey'};
            app.get_msg_type_dropdown.ValueChangedFcn = createCallbackFcn(app, @get_msg_type_dropdownValueChanged, true);
            app.get_msg_type_dropdown.Value = 'file_str';
            app.msg_path_textarea = uitextarea(app.Panel_2);
            app.msg_path_textarea.Editable = 'off';
            app.msg_path_textarea.Value = {'路径'; ''};
            app.Label_18 = uilabel(app.Panel_2);
            app.Label_18.Text = '分裂';
            app.embed_splite_editfield = uieditfield(app.Panel_2, 'numeric');
            app.embed_splite_editfield.ValueChangedFcn = createCallbackFcn(app, @embed_splite_editfieldValueChanged, true);
            app.embed_splite_editfield.Limits = [1 Inf];
            app.embed_splite_editfield.Value = 1;
            app.Tab_3 = uitab(app.para_TabGroup);
            app.Tab_3.Title = '输出参数';
            app.Tab_3.Units = 'pixels';
            app.Panel_6 = uipanel(app.Tab_3);
            app.Panel_6.Title = '输出';
            app.analyse_para_checkbox = uicheckbox(app.Panel_6);
            app.analyse_para_checkbox.ValueChangedFcn = createCallbackFcn(app, @analyse_para_checkboxValueChanged, true);
            app.analyse_para_checkbox.Text = '数据';
            app.get_output_dir_path_btn = uibutton(app.Panel_6, 'push');
            app.get_output_dir_path_btn.ButtonPushedFcn = createCallbackFcn(app, @get_output_dir_path_btnButtonPushed, true);
            app.get_output_dir_path_btn.Text = '修改';
            app.output_file_path_textarea = uitextarea(app.Panel_6);
            app.output_file_path_textarea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RDHS_TDHSce_CodingWnd()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TDHSce_Coding)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDHSce_Coding)
        end
    end
end