classdef RDHS_TDHSce_DecodingWnd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TDHSce_Decoding            matlab.ui.Figure
        mainwnd_btn                matlab.ui.control.Button
        back_btn                   matlab.ui.control.Button
        title_label                matlab.ui.control.Label
        run_btn                    matlab.ui.control.Button
        para_TabGroup              matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        Panel_5                    matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        Label_6                    matlab.ui.control.Label
        img_lamp                   matlab.ui.control.Lamp
        img_preview_panel          matlab.ui.container.Panel
        img_preview_UIAxes         matlab.ui.control.UIAxes
        get_img_btn                matlab.ui.control.Button
        img_path_textarea          matlab.ui.control.TextArea
        Tab_3                      matlab.ui.container.Tab
        Panel_6                    matlab.ui.container.Panel
        analyse_para_checkbox      matlab.ui.control.CheckBox
        get_output_dir_path_btn    matlab.ui.control.Button
        output_file_path_textarea  matlab.ui.control.TextArea
        Label_18                   matlab.ui.control.Label
        output_msg_type_dropdown   matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        FileIO  % Description% Description
        TDHSce % Description
        DC % Description
        DP % Description
        
        %switch_flag
        output_msg_mod
        
        img_ready_flag=false
        analyse_histogram_flag=false
        analyse_para_flag=false
        
        %pararmeter


        ori_bin % Description
        marked_img
        img_H
        img_V
        output_dir_path
        peakleft_pixels
        peakright_pixels
        
        bit_len
        L
        embed_bin_len
        capacity
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = public)


    end
    
    methods (Access = private)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath('bin');addpath('lib');addpath('out');addpath('res');
            
            app.FileIO=File_IO;
            app.TDHSce=Two_Dimensional_Histogram_Shift_Contrast_Enhancement;
            app.DC=Data_Convert;
            app.DP=Data_Process;


            app.img_ready_flag=false;
            app.analyse_histogram_flag=0;
            app.analyse_para_flag=0;
            
            app.bit_len=16;
            app.L;%分裂次数


            app.output_msg_mod=get(app.output_msg_type_dropdown,'Value');
            
            [~,~,~,~,app.output_dir_path] =app.FileIO.getCurPath();
            set(app.output_file_path_textarea,'Value',app.output_dir_path);
        end

        % Callback function
        function StartButtonPushed(app, event)
            
        end

        % Button pushed function: get_img_btn
        function get_img_btnPushed(app, event)
            [~,marked_img_filepath]=app.FileIO.openOutFileDialog('bmp','MarkedImg_TDHSce.bmp');
            ori_marked_img=app.FileIO.readImgFile(marked_img_filepath);
            app.marked_img=double(ori_marked_img);
            ax=app.img_preview_UIAxes;
            imshow(uint8(app.marked_img),'parent',ax);
            set(ax,'visible','off');
            set(app.img_path_textarea,'Value',marked_img_filepath);
            [app.img_H,app.img_V]=size(app.marked_img);
            set(app.img_lamp,'Color',[0,1,0]);
            app.img_ready_flag=true;
        end

        % Button pushed function: run_btn
        function RunButtonPushed(app, event)
            if(app.img_ready_flag)


                input_img=app.marked_img;
                app.L=intmax;%确保可进入循环
                bar = waitbar(0,'预处理中...');    % 显示进度条
                bar_len=0;
                while app.L~=0
                    [output_img,output_bin,app.L]=app.TDHSce.mainDecoding(input_img);
                    input_img=output_img;
                    
                    if(bar_len==0)
                        bar_len=app.L+1;
                    end
                    i=bar_len-app.L;
                    str=['计算中...',num2str(100*i/bar_len),'%'];    % 百分比形式显示处理进程
                    waitbar(i/bar_len,bar,str)                       % 更新进度条bar


                end
                close(bar);
                recoverd_img=output_img;
                recoverd_bin=output_bin;


                if ~exist([app.output_dir_path,'\Extract_TDHSce'])
                    mkdir([app.output_dir_path,'\Extract_TDHSce'])         % 若不存在，在当前目录中产生一个子目录‘Figure’
                end
                output_path=[app.output_dir_path,'\Extract_TDHSce'];
                
                app.FileIO.generateImgFile([output_path,'\RecoverdImg_TDHSce.bmp'],uint8(recoverd_img));
                switch app.output_msg_mod
                    case 'file_str'
                        [ori_msg]=app.DC.bi2StrUni(recoverd_bin);
                        app.FileIO.generateStrFile([output_path,'\RecoverdMsg_str_TDHSce.txt'],ori_msg);
                    case 'file_dec'
                        [ori_msg]=recoverd_bin;
                        app.FileIO.generateDecFile( [output_path,'\RecoverdMsg_TDHSce.txt'],ori_msg)
                    case 'file_img'
                        if(mod(length(recoverd_bin)-28,16)==0)
                            [ori_msg]=app.DC.bi2img(recoverd_bin);
                            app.FileIO.generateImgFile([output_path,'\RecoverdMsgImg_TDHSce.bmp'],ori_msg);
                        else
                            [ori_msg]=recoverd_bin;
                            app.FileIO.generateDecFile( [output_path,'\RecoverdMsg_TDHSce.txt'],ori_msg)
                            error('illegal img ;forced ouput file');
                        end
                    case 'msgbox'
                        [ori_msg]=num2str(recoverd_bin);
                        msgbox(ori_msg);
                    otherwise
                        error('illegal parameter');
                end
                
                
                if(app.analyse_para_flag)
                    %比较文件
                    
                    psnr=app.DP.psnr(app.marked_img,recoverd_img,8);
                    mse=app.DP.mse(app.marked_img,recoverd_img);
                    msgbox(['PSNR：',num2str(psnr),' MSE:',num2str(mse)],'Data');
                end
                
                
                msgbox('Sucess!');
            else
                
                msgbox('not ready','warning');
            end
            
            
        end

        % Callback function
        function get_msg_type_dropdownValueChanged(app, event)
            value = app.get_msg_type_dropdown.Value;
            set(app.msg_lamp,'Color',[1,0,0])
            if(strcmp(value,'input'))
                set(app.msg_preview_textarea,'Editable','on');
            end
            app.msg_ready_flag=false;
            app.input_msg_mod=value;
        end

        % Callback function
        function get_msg_btnButtonPushed(app, event)


            switch app.input_msg_mod
                case 'file'
                    [~,para_msg_filepath]=app.FileIO.openOutFileDialog('txt','para_coding_TDHS.txt');
                    [app.peakleft_pixels,app.peakright_pixels,app.L]=app.TDHS.getParaByFile(para_msg_filepath);
                    set(app.msg_path_textarea,'Value',para_msg_filepath);
                    set(app.msg_preview_textarea,'Editable','off');
                otherwise
                    error('get msg mode error');
            end
            tempstr= dlmread(para_msg_filepath) ;
            set(app.msg_preview_textarea,'Value', num2str(tempstr));
            set(app.msg_lamp,'Color',[0,1,0]);
            app.msg_ready_flag=true;
        end

        % Callback function
        function msg_preview_textareaValueChanged(app, event)
            value = app.msg_preview_textarea.Value;
            app.ori_bin=value;
        end

        % Callback function
        function encrypt_switchValueChanged(app, event)
            value = app.encrypt_switch.Value;
            if(app.RC4_ready_flag&&app.RC4_output_path_flag)
                
                switch value
                    case 'On'
                        app.RC4_flag=true;
                    case 'Off'
                        
                        app.RC4_flag=false;
                        set(app.RC4_key_lamp,'Color',[1,0,0]);
                        set(app.RC4_output_path_lamp,'Color',[1,0,0]);
                        
                        set(app.RC4_key_EditField,'Value','key');
                        set(app.RC4_path_textarea,'Value','path');
                        
                end
                if(app.RC4_flag)%RC4 加密开启后强制数据类型位‘U’
                    app.data_type='U';
                    set(app.character_support_switch,'Value','On');
                end
            else
                switch value
                    case 'On'
                        msgbox('not ready','warning');
                        set(app.encrypt_switch,'Value','Off');
                    case 'Off'
                end
                
            end
        end

        % Callback function
        function character_support_switchValueChanged(app, event)
            value = app.character_support_switch.Value;
            switch value
                case 'On'
                    app.data_type='U';
                    
                case 'Off'
                    app.data_type='A';
            end
        end

        % Callback function
        function get_RC4_Key_btnButtonPushed(app, event)
            
            
        end

        % Callback function
        function get_RC4_output_path_btnButtonPushed(app, event)
            
            
        end

        % Button pushed function: get_output_dir_path_btn
        function get_output_dir_path_btnButtonPushed(app, event)
            app.output_dir_path=app.FileIO.openDirDialog('out','输出文件路径');
            set(app.output_file_path_textarea,'Value',app.output_dir_path);
        end

        % Callback function
        function analyse_img_checkboxValueChanged(app, event)
            value = app.analyse_img_checkbox.Value;
            switch value
                case 1
                    app.analyse_img_flag=true;
                case 0
                    app.analyse_img_flag=false;
            end
            
        end

        % Callback function
        function analyse_histogram_checkboxValueChanged(app, event)
            value = app.analyse_histogram_checkbox.Value;
            switch value
                case 1
                    app.analyse_histogram_flag=true;
                case 0
                    app.analyse_histogram_flag=false;
            end
        end

        % Value changed function: analyse_para_checkbox
        function analyse_para_checkboxValueChanged(app, event)
            value = app.analyse_para_checkbox.Value;
            switch value
                case 1
                    app.analyse_para_flag=true;
                case 0
                    app.analyse_para_flag=false;
            end
        end

        % Button pushed function: back_btn
        function back_btnButtonPushed(app, event)
            RDHS_TDHSce_CodingWnd;
            delete(app);
        end

        % Button pushed function: mainwnd_btn
        function mainwnd_btnButtonPushed(app, event)
            RDHS_MainWnd;
            delete(app);
        end

        % Value changed function: output_msg_type_dropdown
        function output_msg_type_dropdownValueChanged(app, event)
            value = app.output_msg_type_dropdown.Value;
            app.output_msg_mod=value;
        end

        % Callback function
        function embed_chanel_btnPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TDHSce_Decoding = uifigure;
            app.TDHSce_Decoding.Name = 'TDHSce_Decoding';
            setAutoResize(app, app.TDHSce_Decoding, true)
            app.mainwnd_btn = uibutton(app.TDHSce_Decoding, 'push');
            app.mainwnd_btn.ButtonPushedFcn = createCallbackFcn(app, @mainwnd_btnButtonPushed, true);
            app.mainwnd_btn.Text = '首页';
            app.back_btn = uibutton(app.TDHSce_Decoding, 'push');
            app.back_btn.ButtonPushedFcn = createCallbackFcn(app, @back_btnButtonPushed, true);
            app.back_btn.Text = 'Coding→';
            app.title_label = uilabel(app.TDHSce_Decoding);
            app.title_label.Text = 'Two_Dimensional_Histogram_Shift_Coding_with_CE RDHS';
            app.run_btn = uibutton(app.TDHSce_Decoding, 'push');
            app.run_btn.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.run_btn.Text = '运行';
            app.para_TabGroup = uitabgroup(app.TDHSce_Decoding);
            app.Tab = uitab(app.para_TabGroup);
            app.Tab.Title = '必选参数';
            app.Tab.Units = 'pixels';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '必选';
            app.Panel = uipanel(app.Panel_5);
            app.Panel.Title = '图像';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '载体图像';
            app.img_lamp = uilamp(app.Panel);
            app.img_preview_panel = uipanel(app.Panel);
            app.img_preview_panel.Title = '预览图像';
            app.img_preview_UIAxes = uiaxes(app.img_preview_panel);
            app.get_img_btn = uibutton(app.Panel, 'push');
            app.get_img_btn.ButtonPushedFcn = createCallbackFcn(app, @get_img_btnPushed, true);
            app.get_img_btn.Text = '获取';
            app.img_path_textarea = uitextarea(app.Panel);
            app.img_path_textarea.Editable = 'off';
            app.img_path_textarea.Value = {'路径'};
            app.Tab_3 = uitab(app.para_TabGroup);
            app.Tab_3.Title = '输出参数';
            app.Tab_3.Units = 'pixels';
            app.Panel_6 = uipanel(app.Tab_3);
            app.Panel_6.Title = '输出';
            app.analyse_para_checkbox = uicheckbox(app.Panel_6);
            app.analyse_para_checkbox.ValueChangedFcn = createCallbackFcn(app, @analyse_para_checkboxValueChanged, true);
            app.analyse_para_checkbox.Text = '数据';
            app.get_output_dir_path_btn = uibutton(app.Panel_6, 'push');
            app.get_output_dir_path_btn.ButtonPushedFcn = createCallbackFcn(app, @get_output_dir_path_btnButtonPushed, true);
            app.get_output_dir_path_btn.Text = '修改';
            app.output_file_path_textarea = uitextarea(app.Panel_6);
            app.output_file_path_textarea.Editable = 'off';
            app.Label_18 = uilabel(app.Panel_6);
            app.Label_18.Text = '输出方式';
            app.output_msg_type_dropdown = uidropdown(app.Panel_6);
            app.output_msg_type_dropdown.Items = {'file_str', 'file_dec', 'file_img', 'msgbox'};
            app.output_msg_type_dropdown.ValueChangedFcn = createCallbackFcn(app, @output_msg_type_dropdownValueChanged, true);
            app.output_msg_type_dropdown.Value = 'file_str';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RDHS_TDHSce_DecodingWnd()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TDHSce_Decoding)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDHSce_Decoding)
        end
    end
end